classdef Respiration_25Nov24_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InitializeTab                 matlab.ui.container.Tab
        HeartRatebeatsperminuteLabel  matlab.ui.control.Label
        RespirationRatebreathperminuteLabel  matlab.ui.control.Label
        StopLiveReadingButton         matlab.ui.control.Button
        StartLiveReadingButton        matlab.ui.control.Button
        InitializeSensorsButton       matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SignInTab                     matlab.ui.container.Tab
        UserNameDropDown              matlab.ui.control.DropDown
        UserNameDropDownLabel         matlab.ui.control.Label
        SignInButton                  matlab.ui.control.Button
        CalibrationTab                matlab.ui.container.Tab
        Activity1Tab                  matlab.ui.container.Tab
        InhaleExhaleLabel_A1          matlab.ui.control.Label
        ScoreNum_A1                   matlab.ui.control.Label
        PlayButton_A1                 matlab.ui.control.Button
        HRNumLabel_A1                 matlab.ui.control.Label
        HRLabel_A1                    matlab.ui.control.Label
        ScoreLabel                    matlab.ui.control.Label
        PlayTutorialButton            matlab.ui.control.Button
        InstructionsField             matlab.ui.control.Label
        DiscardScoreButton_A1         matlab.ui.control.Button
        StopButton_A1                 matlab.ui.control.Button
        StatusLabel_A1                matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        TimestampNumLabel_A1          matlab.ui.control.Label
        TimestampLabel_A1             matlab.ui.control.Label
        UsernameFieldLabel            matlab.ui.control.Label
        UsernameLabel                 matlab.ui.control.Label
        countNum                      matlab.ui.control.Label
        CountLabel                    matlab.ui.control.Label
        TimeNum                       matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        SaveScoreButton_A1            matlab.ui.control.Button
        PauseButton_A1                matlab.ui.control.Button
        StartButton_A1                matlab.ui.control.Button
        Activity1Complete5diaphragmaticbreathsLabel  matlab.ui.control.Label
        UIAxes_A1                     matlab.ui.control.UIAxes
        Activity2Tab                  matlab.ui.container.Tab
        ScoreNum_A2                   matlab.ui.control.Label
        PlayButton_A2                 matlab.ui.control.Button
        InhaleExhaleLabel_A2          matlab.ui.control.Label
        Round1NumLabel_A2             matlab.ui.control.Label
        Round1Label                   matlab.ui.control.Label
        HRNumLabel_A2                 matlab.ui.control.Label
        Round3NumLabel_A2             matlab.ui.control.Label
        Round2NumLabel_A2             matlab.ui.control.Label
        countNum_A2                   matlab.ui.control.Label
        Round3Label                   matlab.ui.control.Label
        Round2Label                   matlab.ui.control.Label
        CountLabel_A2                 matlab.ui.control.Label
        ProgressLabel                 matlab.ui.control.Label
        InstructionsField_A2          matlab.ui.control.Label
        HRLabel_A2                    matlab.ui.control.Label
        ScoreLabel_A2                 matlab.ui.control.Label
        PlayTutorialButton_A2         matlab.ui.control.Button
        DiscardScoreButton_A2         matlab.ui.control.Button
        StopButton_A2                 matlab.ui.control.Button
        StatusLabel_A2                matlab.ui.control.Label
        StatusLabelTitle_A2           matlab.ui.control.Label
        TimestampNumLabel_A2          matlab.ui.control.Label
        TimestampLabel_A2             matlab.ui.control.Label
        UsernameFieldLabel_A2         matlab.ui.control.Label
        UsernameLabel_A2              matlab.ui.control.Label
        SaveScoreButton_A2            matlab.ui.control.Button
        PauseButton_A2                matlab.ui.control.Button
        StartButton_A2                matlab.ui.control.Button
        Activity2Label                matlab.ui.control.Label
        UIAxes_A2_ProgressBar         matlab.ui.control.UIAxes
        UIAxes_A2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d %DAQ Interface
        dInfo %DAQ info
        aq_dataT; %time interval in seconds when data points are aquired
        DAQstarted; %boolean about whether or not the the DAQ is running
        

        fname; %file to store data
        recorded; %saved data
        calibration; %calibration matrix
        threshold; %peak threshold
        threshold_exhale; %what counts as a full exhale
        user_scores; %user scores temporary storage

        mTimer; %timer object
        mTimer3; %timer object for test
        mTimer4; %timer object for test
        mTimer_Tutorial; %timer object for simulation
        TutorialCounter; %integer counter for setting the simulation frequency
        mCounter; %integer counter
        mCounter3; %integer counter
        mCounter4; %integer counter
        pbCounter; %progress bar counter
        timer_test; %check that simultaneous timers work

        
        A1_TimerCounter; %integer counter
        A1_Timer; %timer object for activity 1
        A1Timer_period; %timer period
        A1total_time; %total time activity 1 will run for
        A1_time_str; %string cell array of timer numbers
        A1_gameOn; %boolean for whether or not Activity 1 is currently active
        A1_gamePaused; %boolean for whether or not Activity 1 is currently active and paused
        A1_gameComplete;%boolean for whether or not Activity 1 is complete
        A1_PreviousVal; %previous Vout Value
        A1_ThresholdCrossed; %boolean for whether the threshold was crossed on inhale
        A1_Count; %count for number of breaths
        A1_Score; %score for Activity 1

        A1_tutorialStarted; %boolean about whether or not the tutorial is running

        A1_simulateDAQTimer; %timer object for testing Activity 1
        A1_simulateDAQCounter; %timer counter
        A1_pauseCounter;
        A1_maxCount;
        A1_HRCount;
        A1_HR_filtB;
        A1_HR_filtA;
        A1_HR;
        A1rgb_colors;
        
        A2_tutorialStarted; %boolean about whether or not the tutorial is running


        A2_TimerCounter;
        A2_pauseCounter;
        A2_TimerCounterInc;
        A2_Timer;
        A2total_time;
        A2Timer_period;
        A2_rounds;
        A2_onoff;

        A2_gameOn;
        A2_gamePaused;
        A2_ThresholdCrossed;
        A2_maxCount;
        A2_HRCount;
        A2_HR_filtB;
        A2_HR_filtA;
        A2_HR;
        A2rgb_colors;

        A2_gameComplete; % Description
        A2_Count; % Description
        A2_BreathCount;
        A2_Score;
        A2_RoundCount;
        A2_PreviousVal;
        A2_recorded; %saved data
        A2_scoringMatrix;
        
    end
    
    methods (Access = private)


        function plotResp(app, src, evt)

            [data, ts, ~]=read(src, src.ScansAvailableFcnCount,"OutputFormat","Matrix");
         
            plot(app.UIAxes3, ts, data,'Marker','none');
       
            app.recorded=[app.recorded; ts data];


        end

        
        function growCircle(app,ax,thr_circle,inc,thrs)
            h=[];
            h_thrs=[];

            d_color=1/length(inc);
            rgb_colors=[ .5:-d_color:d_color d_color:d_color:0.5+d_color; d_color:d_color:1;  1:-d_color:d_color];
            
            if(thr_circle)
                delete(h_thrs);
                h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
            end
            
            hold(ax,'on');
            t_pause=exp(linspace(log(0.00005), log(0.3),length(inc)));
            for i=1:length(inc)
                axis(ax,[0 6 0 6]);
                delete(h);
                if(thr_circle)
                   delete(h_thrs);
                   if(i>=find(inc>=thrs,1)-1)
                       
                       h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.00,1.00,0.00],'LineStyle','--','LineWidth',5);
                   
                       if(i==find(inc>=thrs,1)-1)
                            app.countNum.Text=num2str(1);
                       end
                   else
                        h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                   end
                end

                h=viscircles(ax,[3 3],inc(i),'Color',rgb_colors(:,i));
                
                hold(ax,'off');
                pause(t_pause(i));
            end
            delete(h);
            delete(h_thrs);
        end

        function shrinkCircle(app,ax,thr_circle,inc,thrs)
            h=[];
            h_thrs=[];

            hold(ax,'on');
            d_color=1/length(inc);
            rgb_colors=[ 0.5+d_color:-d_color:d_color d_color:d_color:0.5;  1:-d_color:d_color;  d_color:d_color:1];
            
            
            if(thr_circle)
                delete(h_thrs);
                h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.00,1.00,0.00],'LineStyle','--','LineWidth',5);
            end
            
            t_pause=exp(linspace(log(0.0000001), log(0.0001),length(inc)));
            for i=1:length(inc)
                axis(ax,[0 6 0 6]);
                delete(h);
                if(thr_circle)
                    delete(h_thrs);
                    
                    if(i<=find(inc<=thrs,1))
                        
                        h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.00,1.00,0.00],'LineStyle','--','LineWidth',5);

                    else
                        h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                    end
                    
                     
                end
                
                h=viscircles(ax,[3 3],inc(i),'Color',rgb_colors(:,i));
                
                hold(ax,'off');
                pause(t_pause(i));
            end
            delete(h);
            delete(h_thrs);
        end

        function grow_shrinkCircle(app,ax,thr_circle,inc,thrs)
            %Activity 1 grow/shrink circle function
            %ax helps identify which axis object to use so we can use this
            %for multiple components
            %thr_circle is a boolean for whether or not to display a
            %threshold circle
            %inc is the range of circles that will be drawn
            %thrs is the threshold value

            hold(ax,'on');

            %make circle grow
            growCircle(app,ax,thr_circle,inc,thrs);
            
            %make circle shrink
            shrinkCircle(app,ax,thr_circle,fliplr(inc),thrs); %so it reverses direction
        end

        function grow_shrinkCircle2(app, ax,thr_circle, inc, thrs)
            %Activity 2 grow/shrink circle function
            %ax helps identify which axis object to use so we can use this
            %for multiple components
            %thr_circle is a boolean for whether or not to display a
            %threshold circle
            %inc is the range of circles that will be drawn
            %thrs is the threshold value

            % %establishing the color gradient
            % d_color=1/length(inc);
            % rgb_colors=[1:-d_color:d_color; d_color:d_color:1; zeros(1,length(inc))];
            % 
            % %drawing a dashed circle for the threshold where we are going
            % %to say that the inhale was a breath
            % if(thr_circle)
            %     viscircles(ax,[3 3],thrs,'Color',rgb_colors(:,find(inc>=thrs,1)),'LineStyle','--');
            % end
            pause(1);
            hold(ax,'on');

            %make circle grow
            growCircle(app,ax,thr_circle,inc,thrs);

            %make circle shrink
            shrinkCircle(app,ax,thr_circle,fliplr(inc),thrs);
        end

        %currently not in use
        function startTimer(app,startT, endT)
            app.mCounter = startT; %start time in seconds
            
            round=1;
            on_off=[ones(30,1) zeros(30,1) ones(30,1)*2 zeros(30,1) ones(30,1)*3 zeros(30,1)];
            app.mTimer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate', 'Period', 1);
            start(app.mTimer);
            
            
            % this is the callback for the timer
            function timerCallback(~, ~)
                if app.mCounter>=endT
                    % update the text area and increment the timer
                    app.TextArea.Value = num2str(app.mCounter);
                    app.mCounter = app.mCounter - 1;
                    if(on_off(180-app.mCounter))
                        round=on_off(180-app.mCounter);
                        xline(app.UIAxes_A2_ProgressBar,180-app.mCounter,'Color','g','LineWidth',1);
                        app.ProgressLabel.Text=append('Progress: Round ',num2str(round),' Diaphragmatic Breathing');
                    else
                        xline(app.UIAxes_A2_ProgressBar,180-app.mCounter,'Color',[0.54, 0.70, 0.67],'LineWidth',1);
                        app.ProgressLabel.Text=append('Progress: Round ',num2str(round),' Rest');
                    end

                else
                    stop(app.mTimer);
                end
            
            end

        end

        function grow_shrinkCircleTimer(app,ax,startT, endT, period)
            %ax helps identify which axis object to use so we can use this
            %for multiple components
            %startT is the time in seconds you want the timer to countdown
            %from 
            %endT is the time in seconds you want the timer to countdown to
            %period is how fast in seconds the timer callback function will
            %be called.

            
            %This is a counter object that counts down from a max
            app.mCounter = startT/period; %start time in # of function calls

            round=1; %Initialize at 1 of 3 rounds

            %array of values that indicate which round we are in or if we
            %are in a rest period
            on_off=[ones(30/period,1) zeros(30/period,1) ones(30/period,1)*2 zeros(30/period,1) ones(30/period,1)*3 zeros(30/period,1)];
            on_off=on_off(:);

            resp_rate=1/5; %1 breath every 5s = 12 breaths per minute
            resp_cycle=(1/resp_rate)/period; %how many updates should the whole cycle finish
            
            %the min/max that will be from the calibration data during real
            %measurements
            min_cal=1.5;
            max_cal=2.25;

            %where the dashed circle will be for the threshold
            thrs=1.9;

            %creates a increment array for the grow period of the simulated
            %breath
            inc_tmp=linspace(min_cal,max_cal,resp_cycle/2);

            %establishing the color gradient for growing and shrinking
            d_color=1/length(inc_tmp);
            
            rgb_colors=[ 0.5:-d_color:d_color d_color:d_color:0.5+d_color 0.5+d_color:-d_color:d_color d_color:d_color:0.5; d_color:d_color:1 1:-d_color:d_color;  1:-d_color:d_color d_color:d_color:1];
            
            
            thr_rgb=[0.00 1.00 0.00];
            
            %drawing the threshold circle
            h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
            pause(1);

            %making the full increment array for growing and shrinking 10
            %times

            inc=repmat([inc_tmp fliplr(inc_tmp)],1,10);

            %making the gradient repeated 10 times
            rgb_colors=repmat(rgb_colors,1,10);

            %making a simulated HR array
            %showing HR decrease during the breathing period
            HR_sim1=linspace(72,65,300);
            
            %making HR generally be 72 during the rest period
            HR_sim2=ones(1,300)*72;

            %showing HR increase back up to 72 at the beginning of the rest
            %period
            HR_sim2(1:50)=linspace(65,72,50);

            %adding in some random fluctuations down
            HR_sim2(51:31:end)=71;
            HR_sim2(51:32:end)=71;
            HR_sim2(51:33:end)=71;
            HR_sim2(51:34:end)=71;
            HR_sim2(51:35:end)=71;
            HR_sim2(51:36:end)=71;
            HR_sim2(51:15:end)=70;
            HR_sim2(51:16:end)=70;
            HR_sim2(51:17:end)=70;
            HR_sim2(51:18:end)=70;

            %rounding up to integers so we don't have any decimels
            HR_sim=[ceil(HR_sim1) ceil(HR_sim2)];

            %repeat it three times for three rounds jic
            HR_sim=repmat(HR_sim,1,3);

            %To show some movement during rest time
            min_cal2=1.5;
            max_cal2=1.7;
            inc2=repmat([linspace(min_cal2,max_cal2,resp_cycle/2) linspace(max_cal2,min_cal2,resp_cycle/2)],1,10);
            
            last_radii=inc(300);

            inc2_start=find(inc2>=last_radii,1);

            inc2_rest=[inc2(inc2_start:end) fliplr(inc2(1:inc2_start-1))];


            %initialize the plot object for the circles so we can delete
            %them as needed
            h=[];

            %initialize the count for round 1
            Round1Num=0;
            app.countNum_A2.Text=num2str(Round1Num);

            %initialize the timer object
            app.mTimer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate', 'Period', period);
            
            %start the timer
            start(app.mTimer);
            
            
            % this is the callback for the timer
            function timerCallback(~, ~)
                if app.mCounter>=endT/period
                    
                    app.mCounter = app.mCounter - 1;

                    i=((startT/period)-app.mCounter); %index based on the elapsed num of cycles
                    
                    %updating the HR number
                    app.HRNumLabel_A2.Text=append(num2str(HR_sim(i)),'bpm');

                    %if we are in a on period
                    if(on_off(i))
                        round=on_off(i); %round number
                        %add a line to the progress bar
                        xline(app.UIAxes_A2_ProgressBar,startT-(app.mCounter*period),'Color','g','LineWidth',1);
                        app.ProgressLabel.Text=append('Progress: Round ',num2str(round),' Diaphragmatic Breathing');
                       
                        %if the next index is when the threshold is met
                        if(inc(i+1)>=thrs&&inc(i)<thrs)
                            Round1Num=Round1Num+1; %then increase the count and update
                            app.countNum_A2.Text=num2str(Round1Num);
                            h_thrs=viscircles(ax,[3 3],thrs,'Color',thr_rgb,'LineStyle','--','LineWidth',5);
                        elseif(inc(i+1)<=thrs&&inc(i)>thrs)
                            h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                        end

                        %if it is the first round (just what we are showing
                        %in the tutorial)
                        if(i<=300)
                            delete(h);
                            h=viscircles(ax,[3 3],inc(i),'Color',rgb_colors(:,i));
                        
                        end
                    
                    %if we are in an off period
                    else
                        if (i==301)
                        %draw a grey threshold line over the green one
                            delete(h_thrs);
                            h_thrs=viscircles(ax,[3 3],thrs,'Color',[0.75 0.75 0.75],'LineStyle','--','LineWidth',5);
                        end
                        %add a line to the progress bar
                        xline(app.UIAxes_A2_ProgressBar,startT-(app.mCounter*period),'Color',[0.65, 0.65, 0.65],'LineWidth',1);
                        app.ProgressLabel.Text=append('Progress: Round ',num2str(round),' Rest');
                        
                        %clear the previous circle and draw the circle to
                        %be grey
                        delete(h);
                        h=viscircles(ax,[3 3],inc2_rest(i-299),'Color',[0.5 0.5 0.5]);
                    end

                else
                    %stop the timer once we reach the end time
                    stop(app.mTimer);
                end
            
            end

        end


        function plotA1(app, src, evt)

            [data, ts, ~]=read(src, src.ScansAvailableFcnCount,"OutputFormat","Matrix");
             
           
            %when recorded is over 3 seconds, take only the last 2 seconds
            %of data
            if(size(app.recorded,1)>30*app.d.ScansAvailableFcnCount)
                app.recorded=[app.recorded(end-(20*app.d.ScansAvailableFcnCount):end,:,:); ts data];
            else
                app.recorded=[app.recorded; ts data];
            end
    
            avg_Vout=mean(data(:,1));

            if(avg_Vout>max(app.A1rgb_colors(1,:)))
                rgb_assigned=app.A1rgb_colors(2:4,end);
            elseif((avg_Vout<min(app.A1rgb_colors(1,:))))
                rgb_assigned=app.A1rgb_colors(2:4,1);
            else
                rgb_i=find(app.A1rgb_colors(1,:)>avg_Vout,1,'first');
                
                rgb_assigned=app.A1rgb_colors(2:4,rgb_i);
            end
            
            app.A1_HRCount=app.A1_HRCount+1;


            if(app.A1_gameOn && ~app.A1_gamePaused)
                
                hold(app.UIAxes_A1,"on");
                
                
                %threshold was crossed on inhale
                if(~app.A1_ThresholdCrossed && avg_Vout>=app.threshold && app.A1_PreviousVal<app.threshold)
                    
                    app.A1_ThresholdCrossed=1;
                    app.A1_Count=app.A1_Count+1;
                    app.countNum.Text=num2str(app.A1_Count);
                    app.A1_Score=app.A1_Score+(floor(app.A1_TimerCounter*app.A1Timer_period))+1000;
                    app.ScoreNum_A1.Text=num2str(app.A1_Score);
                    app.InhaleExhaleLabel_A1.Text="exhale";
   
                    cla(app.UIAxes_A1);
                    hold(app.UIAxes_A1,"on");
                    if(exist("h_thr","var"))
                        delete(h_thr);
                    end
        
                    if(exist("h","var"))
                        delete(h);
                    end
                    h_thr=viscircles(app.UIAxes_A1,[3 3],app.threshold,'Color',[0.00 1.00 0.00],'LineStyle','--','LineWidth',5);
                    h=viscircles(app.UIAxes_A1,[3 3],avg_Vout,'Color',rgb_assigned);


                %threshold was crossed on inhale then crossed the exhale threshold 
                elseif(app.A1_ThresholdCrossed && avg_Vout<app.threshold_exhale && app.A1_PreviousVal>=app.threshold_exhale)
                   if(exist("h_thr","var"))
                        delete(h_thr);
                    end
        
                    if(exist("h","var"))
                        delete(h);
                    end

                    cla(app.UIAxes_A1);
                    hold(app.UIAxes_A1,"on");
     
                    h_thr=viscircles(app.UIAxes_A1,[3 3],app.threshold,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                    h=viscircles(app.UIAxes_A1,[3 3],avg_Vout,'Color',rgb_assigned);
                    
    
                    app.A1_ThresholdCrossed=0;
                    app.InhaleExhaleLabel_A1.Text="inhale";

                
                %threshold was previously crossed but not crossed on the exhale
                elseif(app.A1_ThresholdCrossed && avg_Vout>app.threshold_exhale)

                    cla(app.UIAxes_A1);
                    hold(app.UIAxes_A1,"on");
    
                    if(exist("h_thr","var"))
                        delete(h_thr);
                    end

                    if(exist("h","var"))
                        delete(h);
                    end

                    h_thr=viscircles(app.UIAxes_A1,[3 3],app.threshold,'Color',[0.0,1.00,0.00],'LineStyle','--','LineWidth',5);
                    h=viscircles(app.UIAxes_A1,[3 3],avg_Vout,'Color',rgb_assigned);


                %threshold was not crossed on inhale
                else
                    cla(app.UIAxes_A1);
                    hold(app.UIAxes_A1,"on");
    
                    if(exist("h_thr","var"))
                        delete(h_thr);
                    end

                    if(exist("h","var"))
                        delete(h);
                    end

                    h_thr=viscircles(app.UIAxes_A1,[3 3],app.threshold,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                    h=viscircles(app.UIAxes_A1,[3 3],avg_Vout,'Color',rgb_assigned);


                end
    
    
                app.A1_PreviousVal=avg_Vout;
    
                if(app.A1_Count==5)
                    app.A1_gameOn=1;
                    app.A1_gameComplete=1; %game stops because they completed the game
                    cla(app.UIAxes_A1);
                    app.InhaleExhaleLabel_A1.Text="";
                    
                    app.PlayButton_A1.Visible="on";
                    app.PlayButton_A1.Enable="off";
                    app.PauseButton_A1.Visible="on";
                    app.PauseButton_A1.Enable="off";
                    app.StopButton_A1.Visible="on";
                    app.StopButton_A1.Enable="off";
    
                    %enable the save and discard buttons
                    app.SaveScoreButton_A1.Visible="on";
                    app.SaveScoreButton_A1.Enable="on";
                    app.DiscardScoreButton_A1.Visible="on";
                    app.DiscardScoreButton_A1.Enable="on";
    
                    %turn on timestamps
                    app.TimestampNumLabel_A1.Text=string(datetime("now"));
                    app.TimestampNumLabel_A1.Visible="on";
                    app.TimestampLabel_A1.Visible="on";
    
                    disp('DAQ stopped');  
                    stop(app.d);
                    flush(app.d);
                end
              
                if(app.A1_HRCount>=30 && ~rem(app.A1_HRCount,20))
                    i=length(app.recorded(:,3))-(20*app.d.ScansAvailableFcnCount)+1:length(app.recorded(:,3));
                    ts_temp=app.recorded(i,1);
                    data_filtered = filtfilt(app.A1_HR_filtB,app.A1_HR_filtA,app.recorded(i,3));

                    thres=3*std(data_filtered);

                    [pks, pkids]=findpeaks(data_filtered,'MinPeakProminence',thres,'MinPeakWidth',100);
                    
                    if(length(pks)>1)
                        data_HR=60/mean(diff(ts_temp(pkids))); %diff gives the time interval between each peak, 60s/1min * 2pks/interval(s)
                        %data_HR=(length(pks)/2)*60;
                        if data_HR>120
                            app.HRNumLabel_A1.FontColor=[1.0 0.0 0.0];
                        else
                            app.HRNumLabel_A1.FontColor=[0.0 0.0 0.0];
                        end

                        app.HRNumLabel_A1.Text=append(num2str(ceil(data_HR)),'BPM');
                        app.A1_HR=[app.A1_HR ceil(data_HR)];
            
                    else
                        app.HRNumLabel_A1.FontColor=[1.0 0.0 0.0];
                        app.HRNumLabel_A1.Text='<40BPM';
                        app.A1_HR=[app.A1_HR NaN];
                    end
             
                else
                    app.A1_HR=[app.A1_HR NaN];
                    
                end

            end
            
        end

        function simulateDAQ_A1(app)

            if(~app.A1_gamePaused)
                resp_rate=1/5; %1 breath every 5s = 12 breaths per minute
                resp_cycle=(1/resp_rate)/app.A1Timer_period; %how many updates should the whole cycle finish
                
                %the min/max that will be from the calibration data during real
                %measurements
                min_cal=1.1;
                max_cal=2.4;
    
                %where the dashed circle will be for the threshold
                thrs=1.9;
                app.threshold=1.9;
                app.threshold_exhale=1.3;
    
                %creates a increment array for the grow period of the simulated
                %breath
                inc_tmp=linspace(min_cal,max_cal,resp_cycle/2);

                
                %drawing the threshold circle
                h_thrs=viscircles(app.UIAxes_A1,[3 3],thrs,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                
    
                %making the full increment array for growing and shrinking 10
                %times
    
                data=repmat([inc_tmp fliplr(inc_tmp)],1,10);


                %establishing the color gradient for growing and shrinking
                d_color=1/length(inc_tmp);
                
                rgb_colors=[ 0.5:-d_color:d_color d_color:d_color:0.5+d_color 0.5+d_color:-d_color:d_color d_color:d_color:0.5; d_color:d_color:1 1:-d_color:d_color;  1:-d_color:d_color d_color:d_color:1];
                
                
                thr_rgb=[0.00 1.00 0.00];
                
                %drawing the threshold circle
                h_thrs=viscircles(app.UIAxes_A1,[3 3],thrs,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                pause(1);
    
                %making the full increment array for growing and shrinking 10
                %times
    
                inc=repmat([inc_tmp fliplr(inc_tmp)],1,10);
    
                %making the gradient repeated 10 times
                rgb_colors=repmat(rgb_colors,1,10);
    
    
                app.A1_simulateDAQTimer=timer('TimerFcn', @A1_simulateDAQTimerCallback, 'ExecutionMode', 'FixedRate', 'Period', app.A1Timer_period);
                
                app.A1_simulateDAQCounter=1;
                h=[];
                app.A1_ThresholdCrossed=0;
                
                start(app.A1_simulateDAQTimer);
            
            end

            function A1_simulateDAQTimerCallback(~,~)
                if(app.A1_gameOn && ~app.A1_gamePaused)
                    avg_Vout=mean(data(app.A1_simulateDAQCounter:app.A1_simulateDAQCounter+10));
                    
                    hold(app.UIAxes_A1,"on");
                    
                    if(exist("h","var"))
                        delete(h);
                    end
                    
    
                    h=viscircles(app.UIAxes_A1,[3 3],avg_Vout,'Color',rgb_colors(:,app.A1_maxCount-app.A1_TimerCounter));
                    
                    
                    %threshold was crossed on inhale
                    if(~app.A1_ThresholdCrossed && avg_Vout>=app.threshold && app.A1_PreviousVal<app.threshold)
                        
                        app.A1_ThresholdCrossed=1;
                        app.A1_Count=app.A1_Count+1;
                        app.countNum.Text=num2str(app.A1_Count);
                        app.A1_Score=app.A1_Score+(floor(app.A1_TimerCounter*app.A1Timer_period))+1000;
                        app.ScoreNum_A1.Text=num2str(app.A1_Score);
                        app.InhaleExhaleLabel_A1.Text="exhale";
        
                        if(exist("h_thr","var"))
                            delete(h_thr);
                        end
                    
                        h_thr=viscircles(app.UIAxes_A1,[3 3],app.threshold,'Color',[0.00 1.00 0.00],'LineStyle','--','LineWidth',5);
        
                        
                    %threshold was crossed on inhale then crossed the exhale threshold 
                    elseif(app.A1_ThresholdCrossed && avg_Vout<app.threshold_exhale && app.A1_PreviousVal>=app.threshold_exhale)
                        
                        app.A1_ThresholdCrossed=0;
        
                        if(exist("h_thr","var"))
                            delete(h_thr);
                        end
        
                        h_thr=viscircles(app.UIAxes_A1,[3 3],app.threshold,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                        
                        app.InhaleExhaleLabel_A1.Text="inhale";
        
                    end
  
        
                    app.A1_PreviousVal=avg_Vout;
        
                    if(app.A1_Count==5)
                        app.A1_gameOn=1;
                        app.A1_gameComplete=1; %game stops because they completed the game
                        cla(app.UIAxes_A1);
                        app.InhaleExhaleLabel_A1.Text="";
                        
                        app.PlayButton_A1.Visible="on";
                        app.PlayButton_A1.Enable="off";
                        app.PauseButton_A1.Visible="on";
                        app.PauseButton_A1.Enable="off";
                        app.StopButton_A1.Visible="on";
                        app.StopButton_A1.Enable="off";
        
                        %enable the save and discard buttons
                        app.SaveScoreButton_A1.Visible="on";
                        app.SaveScoreButton_A1.Enable="on";
                        app.DiscardScoreButton_A1.Visible="on";
                        app.DiscardScoreButton_A1.Enable="on";
        
                        %turn on timestamps
                        app.TimestampNumLabel_A1.Text=string(datetime("now"));
                        app.TimestampNumLabel_A1.Visible="on";
                        app.TimestampLabel_A1.Visible="on";
        
                        disp('DAQ stopped');  
                        stop(app.A1_simulateDAQTimer);
                    end
                    
    
                    app.A1_simulateDAQCounter=app.A1_simulateDAQCounter+1;
    
                    if(app.A1_simulateDAQCounter>length(data))
    
                        stop(app.A1_simulateDAQTimer);
    
                    end
                end
                
            end
            

        end

        function A1_StartTimer(app)
            
            %Already initialized in preLoad: app.A1_TimerCounter, app.A1_time_str

            if(~app.A1_gamePaused)
                app.A1_maxCount=app.A1_TimerCounter;
    
                app.TimeNum.Text=string(app.A1_time_str(1));

            else
                app.A1_maxCount=app.A1total_time/app.A1Timer_period;
                app.A1_TimerCounter=app.A1_pauseCounter;
                app.TimeNum.Text=string(app.A1_time_str(app.A1_maxCount-app.A1_TimerCounter));
                app.A1_gamePaused=0;
            end

                app.PlayButton_A1.Enable="off";
                app.PlayButton_A1.Visible="on";
                app.PauseButton_A1.Visible="on";
                app.PauseButton_A1.Enable="on";
                app.StopButton_A1.Visible="on";
                app.StopButton_A1.Enable="on";
                
                %initialize the timer object
                app.A1_Timer = timer('TimerFcn', @A1_timerCallback, 'ExecutionMode', 'FixedRate', 'Period', app.A1Timer_period);
                app.A1_TimerCounter=app.A1_TimerCounter-1;
    
                %start the timer
                start(app.A1_Timer);
    
                app.PlayButton_A1.Visible="on";
                app.PlayButton_A1.Enable="off";
                app.InhaleExhaleLabel_A1.Text="inhale";

            function A1_timerCallback(~,~)
                if(app.A1_TimerCounter>0)
                    if(app.A1_gameOn && ~app.A1_gameComplete && ~app.A1_gamePaused) %game is on and not paused
                        app.TimeNum.Text=string(app.A1_time_str(app.A1_maxCount-app.A1_TimerCounter));
                        app.A1_TimerCounter=app.A1_TimerCounter-1;

    
                    elseif(app.A1_gameOn && app.A1_gameComplete) %game is on and the game is complete
                        %Finalize game
                        
                        %enable the save and discard buttons
                        app.SaveScoreButton_A1.Visible="on";
                        app.SaveScoreButton_A1.Enable="on";
                        app.DiscardScoreButton_A1.Visible="on";
                        app.DiscardScoreButton_A1.Enable="on";
    
                        %turn on timestamps
                        app.TimestampNumLabel_A1.Text=string(datetime("now"));
                        app.TimestampNumLabel_A1.Visible="on";
                        app.TimestampLabel_A1.Visible="on";
                        app.InhaleExhaleLabel_A1.Text="";
    
                        stop(app.A1_simulateDAQTimer);
                        stop(app.d);                      %stop daq
                        flush(app.d);                     %clear data in daq buffer


                        app.StatusLabel_A1.Text="Game Complete!";
                        
                      
                        disp('DAQ stopped');

                        stop(app.A1_Timer);
                        app.A1_TimerCounter=app.A1total_time/app.A1Timer_period; %how many times the timer callback will be done in 120s
            
    
                    elseif(~app.A1_gameOn && ~app.A1_gameComplete) %game is stopped but not complete
                        
                        app.TimeNum.Text=string(app.A1_time_str(end));
                        stop(app.d);                      %stop daq
                        flush(app.d);                     %clear data in daq buffer
                        app.SaveScoreButton_A1.Visible="on";
                        app.SaveScoreButton_A1.Enable="on";
                        app.DiscardScoreButton_A1.Visible="on";
                        app.DiscardScoreButton_A1.Enable="on";
                        app.TimestampLabel_A1.Visible="on";
                        app.TimestampNumLabel_A1.Text=string(datetime("now"));
                        app.TimestampNumLabel_A1.Visible="on";
                        app.InhaleExhaleLabel_A1.Text="";
                      
                        disp('DAQ stopped');
                        
                        stop(app.A1_Timer);
                        app.A1_TimerCounter=app.A1total_time/app.A1Timer_period; %how many times the timer callback will be done in 120s
            
                    %else game is paused, don't progress timer
                    end

                else %when the timer is done
                    app.A1_gameOn=0;
                    app.A1_TimerCounter=app.A1total_time/app.A1Timer_period; %how many times the timer callback will be done in 120s
                    app.TimeNum.Text=string(app.A1_time_str(end));
                    stop(app.d);                      %stop daq
                    flush(app.d);                     %clear data in daq buffer
                    app.PauseButton_A1.Visible="on";
                    app.PauseButton_A1.Enable="off";
                    app.StopButton_A1.Visible="on";
                    app.StopButton_A1.Enable="off";
                    app.PlayButton_A1.Visible="on";
                    app.PlayButton_A1.Enable="off";
                    app.SaveScoreButton_A1.Visible="on";
                    app.SaveScoreButton_A1.Enable="on";
                    app.DiscardScoreButton_A1.Visible="on";
                    app.DiscardScoreButton_A1.Enable="on";
                    app.TimestampLabel_A1.Visible="on";
                    app.TimestampNumLabel_A1.Text=string(datetime("now"));
                    app.TimestampNumLabel_A1.Visible="on";
                    app.InhaleExhaleLabel_A1.Text="";

                    app.StatusLabel_A1.Text="Game Over";
                  
                    disp('DAQ stopped');
                    stop(app.A1_Timer);
                end
            end

            
        end
        
        function A2_StartTimer(app)
           
            %This is a counter object that counts down from a max
            %app.A2_TimerCounter;
            %app.A2_onoff;

            if(app.A2_gamePaused) %means the game was paused but is being started again
                app.A2_TimerCounter=app.A2_pauseCounter;
                app.A2_gamePaused=0;
            end

            app.PauseButton_A2.Visible="on";
            app.PauseButton_A2.Enable="on";
            app.StopButton_A2.Visible="on";
            app.StopButton_A2.Enable="on";
                

            round=app.A2_rounds(1); %Initialize at 1 of 3 rounds
            app.A2_TimerCounterInc=0;

            %initialize the timer object
            app.A2_Timer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate', 'Period', app.A2Timer_period);
            
            %start the timer
            start(app.A2_Timer);
            
            
            % this is the callback for the timer
            function timerCallback(~, ~)
                if app.A2_TimerCounter>0 
                     if(app.A2_gameOn && ~app.A2_gameComplete && ~app.A2_gamePaused) %game is on and not paused
                  
                        app.A2_TimerCounter=app.A2_TimerCounter-1;
                        app.A2_TimerCounterInc=app.A2_TimerCounterInc+1;

                        if(app.A2_onoff(app.A2_TimerCounterInc))
                            round=app.A2_onoff(app.A2_TimerCounterInc);
                            %add a line to the progress bar
                            xline(app.UIAxes_A2_ProgressBar,app.A2_TimerCounterInc*app.A2Timer_period,'Color','g','LineWidth',1);
                            app.ProgressLabel.Text=append('Progress: Round ',num2str(round),' Diaphragmatic Breathing');
                        else
                            
                            %add a line to the progress bar
                            xline(app.UIAxes_A2_ProgressBar,app.A2_TimerCounterInc*app.A2Timer_period,'Color',[0.65, 0.65, 0.65],'LineWidth',1);
                            app.ProgressLabel.Text=append('Progress: Round ',num2str(round),' Rest');
                        end


                     elseif(app.A2_gameOn && app.A2_gameComplete) %game is on and the game is complete
                        %Finalize game
                        
                        %enable the save and discard buttons
                        app.SaveScoreButton_A2.Visible="on";
                        app.SaveScoreButton_A2.Enable="on";
                        app.DiscardScoreButton_A2.Visible="on";
                        app.DiscardScoreButton_A2.Enable="on";
    
                        %turn on timestamps
                        app.TimestampNumLabel_A2.Text=string(datetime("now"));
                        app.TimestampNumLabel_A2.Visible="on";
                        app.TimestampLabel_A2.Visible="on";
                        app.InhaleExhaleLabel_A2.Text="";
    
                        
                        stop(app.d);                      %stop daq
                        flush(app.d);                     %clear data in daq buffer


                        app.StatusLabel_A2.Text="Game Complete!";
                        app.ProgressLabel.Text="";
                        disp('DAQ stopped');

                        stop(app.A2_Timer);
                        app.A2_TimerCounter=app.A2total_time/app.A2Timer_period; %how many times the timer callback will be done
    
                     elseif(~app.A2_gameOn && ~app.A2_gameComplete) %game is stopped but not complete, stopped
                     
                        stop(app.d);                      %stop daq
                        flush(app.d);                     %clear data in daq buffer
                        app.SaveScoreButton_A2.Visible="on";
                        app.SaveScoreButton_A2.Enable="on";
                        app.DiscardScoreButton_A2.Visible="on";
                        app.DiscardScoreButton_A2.Enable="on";
                        app.TimestampLabel_A2.Visible="on";
                        app.TimestampNumLabel_A2.Text=string(datetime("now"));
                        app.TimestampNumLabel_A2.Visible="on";
                        app.InhaleExhaleLabel_A2.Text="";
                        app.StatusLabel_A2.Text="Game Stopped.";
                        app.ProgressLabel.Text="";
                        disp('DAQ stopped');
                        
                        stop(app.A2_Timer);
                        app.A2_TimerCounter=app.A2total_time/app.A2Timer_period; %how many times the timer callback will be done
                        
                     end

                else
                    %stop the timer once we reach the end time
                    app.A2_gameOn=0;
                    
                    app.StatusLabel_A2.Text="Game Complete!";
                    app.ProgressLabel.Text="";
                     
                    stop(app.d);                      %stop daq
                    flush(app.d);                     %clear data in daq buffer
            
                    app.PauseButton_A2.Visible="on";
                    app.PauseButton_A2.Enable="off";
                    app.StopButton_A2.Visible="on";
                    app.StopButton_A2.Enable="off";
                    app.PlayButton_A2.Visible="on";
                    app.PlayButton_A2.Enable="off";

                    app.SaveScoreButton_A2.Visible="on";
                    app.SaveScoreButton_A2.Enable="on";
                    app.DiscardScoreButton_A2.Visible="on";
                    app.DiscardScoreButton_A2.Enable="on";
                    app.TimestampLabel_A2.Visible="on";
                    app.TimestampNumLabel_A2.Text=string(datetime("now"));
                    app.TimestampNumLabel_A2.Visible="on";
                    app.InhaleExhaleLabel_A2.Text="";
                  
                    disp('DAQ stopped');
                    app.SaveScoreButton_A1.Visible="on";
                    app.SaveScoreButton_A1.Enable="on";
                    app.DiscardScoreButton_A1.Visible="on";
                    app.DiscardScoreButton_A1.Enable="on";
                    app.TimestampLabel_A1.Visible="on";
                    stop(app.A2_Timer);
                    app.A2_TimerCounter=app.A2total_time/app.A2Timer_period; %how many times the timer callback will be done
                      
                end
            
            end

        end
        
        function plotA2(app, src, evt)
            [data, ts, ~]=read(src, src.ScansAvailableFcnCount,"OutputFormat","Matrix");
             
           
            %when recorded is over 3 seconds, take only the last 2 seconds
            %of data
            if(size(app.A2_recorded,1)>30*app.d.ScansAvailableFcnCount)
                app.A2_recorded=[app.A2_recorded(end-(20*app.d.ScansAvailableFcnCount):end,:,:); ts data];
            else
                app.A2_recorded=[app.A2_recorded; ts data];
            end
            
    
            avg_Vout=mean(data(:,1));

            if(avg_Vout>max(app.A2rgb_colors(1,:)))
                
                rgb_assigned=app.A2rgb_colors(2:4,end);

            elseif((avg_Vout<min(app.A2rgb_colors(1,:))))
                
                rgb_assigned=app.A2rgb_colors(2:4,1);
            
            else
                rgb_i=find(app.A2rgb_colors(1,:)>avg_Vout,1,'first');
                
                rgb_assigned=app.A2rgb_colors(2:4,rgb_i);
            end
            
            app.A2_HRCount=app.A2_HRCount+1;


            if(app.A2_gameOn && ~app.A2_gamePaused) %if the game is running and not paused

                %if on/off array is on
                if(app.A2_onoff(app.A2_TimerCounterInc))
                    
                    %threshold was crossed on inhale
                    if(~app.A2_ThresholdCrossed && avg_Vout>=app.threshold && app.A2_PreviousVal<app.threshold)

                        app.A2_ThresholdCrossed=1;
                        app.A2_Count=app.A2_Count+1;
                        app.countNum_A2.Text=num2str(app.A2_Count);
                        app.A2_Score=app.A2_Score+app.A2_scoringMatrix(app.A2_TimerCounterInc)+1000;
                        app.ScoreNum_A2.Text=num2str(app.A2_Score);
                        app.InhaleExhaleLabel_A2.Text="exhale";

                        cla(app.UIAxes_A2);
                        hold(app.UIAxes_A2,"on");
                        if(exist("h_thr","var"))
                            delete(h_thr);
                        end
    
                        if(exist("h","var"))
                            delete(h);
                        end
                        h_thr=viscircles(app.UIAxes_A2,[3 3],app.threshold,'Color',[0.00 1.00 0.00],'LineStyle','--','LineWidth',5);
                        h=viscircles(app.UIAxes_A2,[3 3],avg_Vout,'Color',rgb_assigned);
                        

                    %threshold was crossed on inhale then crossed the exhale threshold
                    elseif(app.A2_ThresholdCrossed && avg_Vout<app.threshold_exhale && app.A2_PreviousVal>=app.threshold_exhale)
                        if(exist("h_thr","var"))
                            delete(h_thr);
                        end
    
                        if(exist("h","var"))
                            delete(h);
                        end
    
                        cla(app.UIAxes_A2);
                        hold(app.UIAxes_A2,"on");

                        h_thr=viscircles(app.UIAxes_A2,[3 3],app.threshold,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                        h=viscircles(app.UIAxes_A2,[3 3],avg_Vout,'Color',rgb_assigned);
                        
    
                        app.A2_ThresholdCrossed=0;
                        app.InhaleExhaleLabel_A2.Text="inhale";

                    %threshold was previously crossed but not crossed on the exhale
                    elseif(app.A2_ThresholdCrossed && avg_Vout>app.threshold_exhale)

                        cla(app.UIAxes_A2);
                        hold(app.UIAxes_A2,"on");
    
                        if(exist("h_thr","var"))
                            delete(h_thr);
                        end
    
                        if(exist("h","var"))
                            delete(h);
                        end
                        h_thr=viscircles(app.UIAxes_A2,[3 3],app.threshold,'Color',[0.0,1.00,0.00],'LineStyle','--','LineWidth',5);
                        h=viscircles(app.UIAxes_A2,[3 3],avg_Vout,'Color',rgb_assigned);


                    %threshold was not crossed on inhale
                    else
                        cla(app.UIAxes_A2);
                        hold(app.UIAxes_A2,"on");
    
                        if(exist("h_thr","var"))
                            delete(h_thr);
                        end
    
                        if(exist("h","var"))
                            delete(h);
                        end

                        h_thr=viscircles(app.UIAxes_A2,[3 3],app.threshold,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
                        h=viscircles(app.UIAxes_A2,[3 3],avg_Vout,'Color',rgb_assigned);


                    end
                
                %if on/off array is off
                else
                   %if the index before is true
                   if(app.A2_TimerCounterInc>1) %make sure its not the first index
                       if(app.A2_onoff(app.A2_TimerCounterInc-1)==1) %if the last round was round 1
                            app.Round1NumLabel_A2.Text=num2str(app.A2_Count);
                            app.A2_BreathCount(1)=app.A2_Count;
                       elseif(app.A2_onoff(app.A2_TimerCounterInc-1)==2)%if the last round was round 2
                           app.Round2NumLabel_A2.Text=num2str(app.A2_Count);
                           app.A2_BreathCount(2)=app.A2_Count;
                       elseif(app.A2_onoff(app.A2_TimerCounterInc-1)==3)%if the last round was round 3
                           app.Round3NumLabel_A2.Text=num2str(app.A2_Count);
                           app.A2_BreathCount(3)=app.A2_Count;
                       end  
                   end

                    %if next index is true and not the end, reset the count
                    %value and update the round number
                    if(app.A2_TimerCounterInc<length(app.A2_onoff) && ~app.A2_onoff(app.A2_TimerCounterInc) && app.A2_onoff(app.A2_TimerCounterInc+1))
                        app.A2_RoundCount=[app.A2_RoundCount app.A2_Count];
                        app.A2_Count=0;
                    elseif app.A2_TimerCounterInc==length(app.A2_onoff) %if its the last round still save the count at the last index
                        app.A2_RoundCount=[app.A2_RoundCount app.A2_Count];
                        app.A2_Count=0;
                    end
                 
                    
                    %make threshold grey and circle grey
                    %don't count threshold crossings

                    cla(app.UIAxes_A2);
                    hold(app.UIAxes_A2,"on");

                    if(exist("h_thr","var"))
                        delete(h_thr);
                    end

                    if(exist("h","var"))
                        delete(h);
                    end

                    h_thr=viscircles(app.UIAxes_A2,[3 3],app.threshold,'Color',[0.75,0.75,0.75],'LineStyle','--','LineWidth',5);
                    h=viscircles(app.UIAxes_A2,[3 3],avg_Vout,'Color',[0.65 0.65 0.65]);


                end
                app.A2_PreviousVal=avg_Vout;
               

                %HR update after first 2seconds then every second after
                %that
                if(app.A2_HRCount>=30 && ~rem(app.A2_HRCount,20))
                    i=length(app.A2_recorded(:,3))-(20*app.d.ScansAvailableFcnCount)+1:length(app.A2_recorded(:,3));

                    data_filtered = filtfilt(app.A2_HR_filtB,app.A2_HR_filtA,app.A2_recorded(i,3));
                    ts_temp=app.A2_recorded(i,1);
                    thres=3*std(data_filtered);

                    [pks, pkids]=findpeaks(data_filtered,'MinPeakProminence',thres,'MinPeakWidth',100);
                    
                    if(length(pks)>1)
                        data_HR=60/mean(diff(ts_temp(pkids))); %diff gives the time interval between each peak, 60s/1min * 2pks/interval(s)
                        %data_HR=(length(pks)/2)*60;
                        if data_HR>120
                            app.HRNumLabel_A2.FontColor=[1.0 0.0 0.0];
                        else
                            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
                        end

                        app.HRNumLabel_A2.Text=append(num2str(ceil(data_HR)),'BPM');
                        app.A2_HR=[app.A2_HR ceil(data_HR)];

                    else
                        app.HRNumLabel_A2.FontColor=[1.0 0.0 0.0];
                        app.HRNumLabel_A2.Text='<40BPM';
                        app.A2_HR=[app.A2_HR NaN];
                    end

                else
                    app.A2_HR=[app.A2_HR NaN];

                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function preLoad(app)
            app.aq_dataT=0.1;
            app.user_scores=[];
            app.DAQstarted=0;
            
            %initialize A1 variables
            app.A1_ThresholdCrossed=0;
            app.A1Timer_period=0.1;
            app.A1total_time=120;
            app.A1_TimerCounter=app.A1total_time/app.A1Timer_period; %how many times the timer callback will be done in 120s
            
            timer_freq=1/app.A1Timer_period; %how many times called per second
            
            %shift over the times so the very last position has 00:00
            A1_time=floor((app.A1_TimerCounter+timer_freq-2:-1:timer_freq-1)*app.A1Timer_period); %seconds
            A1_minutes=floor(A1_time/60); %minutes over time
            A1_seconds=rem(A1_time,60); %seconds portion over time
            
            A1_minutes_str=split(num2str(A1_minutes));
            
            A1_seconds_str=split(num2str(A1_seconds));
            
            single_digit_i=find(A1_seconds<10);
            
            A1_seconds_str(single_digit_i)=append('0', A1_seconds_str(single_digit_i));
            
            app.A1_time_str=append('0',A1_minutes_str,':',A1_seconds_str);
            app.A1_simulateDAQCounter=0;

            Wn=25/10e3;
            N=1;
            [app.A1_HR_filtB,app.A1_HR_filtA] = butter(N,Wn,'low');

            %initialize A2 variables
            app.A2_ThresholdCrossed=0;
            app.A2Timer_period=0.1;
            app.A2total_time=180;
            app.A2_TimerCounter=app.A2total_time/app.A2Timer_period; %how many times the timer callback will be done in 120s
            app.A2_HR_filtB=app.A1_HR_filtB;
            app.A2_HR_filtA=app.A1_HR_filtA;
            app.A2_rounds=[1 2 3];
            app.A2_onoff=[ones(30/app.A2Timer_period,1) zeros(30/app.A2Timer_period,1) ones(30/app.A2Timer_period,1)*2 zeros(30/app.A2Timer_period,1) ones(30/app.A2Timer_period,1)*3 zeros(30/app.A2Timer_period,1)];
            app.A2_onoff=app.A2_onoff(:);
            tmp_score=[ceil(linspace(120,0,30/app.A2Timer_period))' zeros(30/app.A2Timer_period,1)];
            app.A2_scoringMatrix=repmat(tmp_score(:),3,1);
            app.A2_RoundCount=[];
            

        end

        % Button pushed function: InitializeSensorsButton
        function initDAQ(app, event)
            mydaq=daq("adi"); %create DAQ Object called mydaq
            addinput(mydaq,'smu1','a','Voltage'); %Add chA as the input
            addinput(mydaq,'smu1','b','Voltage'); %Add chB as the input
            mydaq.ScansAvailableFcnCount=app.aq_dataT*mydaq.Rate; %set samples aquired to 0.5s
            app.d=mydaq; %store DAQ object to app property d
            app.dInfo=mydaq.Channels.Device; %store DAQ properties to app property dInfo
          
            disp('DAQ initialized');
        end

        % Button pushed function: StartLiveReadingButton
        function startDAQ(app, event)
            app.DAQstarted=1;
            %call plotTemp when enough scans are available
            %Aqcuiring and plotting
            % create callback function to call plotPPG every time 2s scans available
            app.d.ScansAvailableFcn = @(src,evt) plotResp(app,src,evt);    
            % start acquiring and plotting
            start(app.d, "continuous");

            disp('DAQ started');

        end

        % Button pushed function: StopLiveReadingButton
        function stopDAQ(app, event)
            
            
            app.DAQstarted=0;
            %stop DAQ
            %Clear DAQ buffer and axes

            stop(app.d);                      %stop daq
            flush(app.d);                     %clear data in daq buffer
            cla(app.UIAxes);
            disp('DAQ stopped');
        end

        % Callback function
        function saveData(app, event)
            data=app.recorded;
            save(app.FileNameEditField.Value,'data');
        end

        % Callback function
        function startRecord(app, event)
            value = app.RecordButton.Value;
            app.recorded=[];
        end

        % Button down function: Activity1Tab
        function initActivity1(app, event)
            if(app.DAQstarted)
                stop(app.d);
                flush(app.d);
                app.DAQstarted=0;
            end
            cla(app.UIAxes_A1);
            axis(app.UIAxes_A1,[0 6 0 6]);
            hold(app.UIAxes_A1,'on');
            
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text=string(app.A1_time_str(1));
            app.HRNumLabel_A1.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A1.Text='--';
            
            app.SaveScoreButton_A1.Visible="off";
            app.SaveScoreButton_A1.Enable="off";
            app.DiscardScoreButton_A1.Visible="off";
            app.DiscardScoreButton_A1.Enable="off";
            app.TimestampLabel_A1.Visible="off";
            app.TimestampNumLabel_A1.Visible="off";

            %the min/max that will be from the calibration data during real
            %measurements
            min_cal=1.1;
            max_cal=2.4;

            %creates a increment array for the grow period of the simulated
            %breath
            tmp_increment=100;
            A1rgb_tmp=linspace(min_cal,max_cal,tmp_increment);
            
            %establishing the color gradient for growing and shrinking
            d_color=1/tmp_increment;
            
            app.A1rgb_colors=[A1rgb_tmp; 0.5:-d_color:d_color d_color:d_color:0.5; d_color:d_color:1; 1:-d_color:d_color];
            
        end

        % Button pushed function: PlayTutorialButton
        function startTutorial(app, event)
            if(app.DAQstarted)
                stop(app.d);
                flush(app.d);
                app.DAQstarted=0;
                
            end
            app.PlayButton_A1.Visible="on";
            app.PlayButton_A1.Enable="off";
            app.PauseButton_A1.Visible="on";
            app.PauseButton_A1.Enable="off";
            app.StopButton_A1.Visible="on";
            app.StopButton_A1.Enable="off";
            app.PlayTutorialButton.Visible="on";
            app.PlayTutorialButton.Enable="on";
            app.StartButton_A1.Visible="on";
            app.StartButton_A1.Enable="off";

            app.A1_tutorialStarted=1;

            %The red circle represents your breathing
            app.CountLabel.Text='';
            app.countNum.Text='';
            app.TimeLabel.Text='';
            app.TimeNum.Text='';
            app.InstructionsField.Text='The red circle represents your breathing.';

            h_red=viscircles(app.UIAxes_A1,[3 3],.75,'Color',[0.5 0 1]);
            pause(3);
            %When you breathe in, the circle will grow
            app.InstructionsField.Text='When you breathe in, the circle will grow.';
            pause(3);
            delete(h_red);
            growCircle(app,app.UIAxes_A1,0,[.75:0.01:2.25],1.9);
           
            %When you breathe out, the circle will shrink
            app.InstructionsField.Text='When you breathe out, the circle will shrink.';
            
            shrinkCircle(app,app.UIAxes_A1,0,[2.25:-0.01:.75],1.9);
            
            %The dashed green circle is your goal. Every breath that
            %crosses this threshold will be counted as one breath.
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.InstructionsField.Text='The dashed green circle is your goal. Every inhale that crosses this threshold will be counted as one breath.';
            

            grow_shrinkCircle(app,app.UIAxes_A1,1,[.75:0.01:2.25],1.9);

            pause(1);
            %You have 2mins to reach 5 successful diaphragmatic breaths

            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            
            app.InstructionsField.Text='You have 2mins to finish 5 successful diaphragmatic breaths.';
            pause(5);
            
            cla(app.UIAxes_A1);
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            app.InstructionsField.Text='';
        
            app.A1_tutorialStarted=0;
            app.StartButton_A1.Enable="on";
            
        end

        % Button pushed function: PlayTutorialButton_A2
        function startTutorial2(app, event)
            if(app.DAQstarted)
                stop(app.d);
                flush(app.d);
                app.DAQstarted=0;
            end

            app.A2_tutorialStarted=1;
            % app.InstructionsField_A2.Text='The dashed green circle is your goal. Every inhale that crosses this threshold will be counted as one breath.';
            app.InstructionsField_A2.Text='We will do 3 x 30s Rounds of diaphragmatic breathing. You will have 30s of rest after each round.';
            pause(1);
            grow_shrinkCircleTimer(app,app.UIAxes_A2, 180, 121,.1); %start count down timer that starts at 180s but stops when it reaches 121s for the tutorial
            
            %Stop Timer
            app.A2_gameOn=0;
            app.A2_gamePaused=0;

            app.PlayButton_A2.Visible="on";
            app.PlayButton_A2.Enable="off";
            app.PauseButton_A2.Visible="on";
            app.PauseButton_A2.Enable="off";
            app.StopButton_A2.Visible="on";
            app.StopButton_A2.Enable="off";
            app.PlayTutorialButton_A2.Visible="on";
            app.PlayTutorialButton_A2.Enable="on";
            app.StartButton_A2.Visible="on";
            app.StartButton_A2.Enable="off";
         
            app.StatusLabel_A2.Text="Incomplete";
            app.TimestampLabel_A2.Visible="off";
            app.TimestampNumLabel_A2.Visible="off";
            app.InhaleExhaleLabel_A2.Text="";

            app.A2_TimerCounter=app.A2total_time/app.A2Timer_period;
            cla(app.UIAxes_A2);
            cla(app.UIAxes_A2_ProgressBar);
            axis(app.UIAxes_A2,[0 6 0 6]);
            hold(app.UIAxes_A2,'on');

            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A2.Text='--';
            app.A2_HRCount=0;
            app.countNum_A2.Text="";
            app.A2_tutorialStarted=0;

            app.StartButton_A2.Enable="on";
        end

        % Button down function: Activity2Tab
        function initActivity2(app, event)
            if(app.DAQstarted)
                stop(app.d);
                flush(app.d);
                app.DAQstarted=0;
            end

            cla(app.UIAxes_A2_ProgressBar);
            cla(app.UIAxes_A2);
            axis(app.UIAxes_A2,[0 6 0 6]);
     
            hold(app.UIAxes_A2,'on');

            cla(app.UIAxes_A2_ProgressBar);
            axis(app.UIAxes_A2_ProgressBar,[0 180 0 10]);
            
            app.CountLabel_A2.Text='Breath Count:';
            app.countNum_A2.Text='0';
            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A2.Text='--';
            app.Round1NumLabel_A2.Text="--";
            app.Round2NumLabel_A2.Text="--";
            app.Round3NumLabel_A2.Text="--";
            app.countNum_A2.Text="";

            app.SaveScoreButton_A2.Visible="off";
            app.SaveScoreButton_A2.Enable="off";
            app.DiscardScoreButton_A2.Visible="off";
            app.DiscardScoreButton_A2.Enable="off";
            app.TimestampLabel_A2.Visible="off";
            app.TimestampNumLabel_A2.Visible="off";

            %the min/max that will be from the calibration data during real
            %measurements
            min_cal=1.1;
            max_cal=2.4;

            %creates a increment array for the grow period of the simulated
            %breath
            tmp_increment=100;
            A2rgb_tmp=linspace(min_cal,max_cal,tmp_increment);
            
            %establishing the color gradient for growing and shrinking
            d_color=1/tmp_increment;
            
            app.A2rgb_colors=[A2rgb_tmp; 0.5:-d_color:d_color d_color:d_color:0.5; d_color:d_color:1; 1:-d_color:d_color];

            
        end

        % Button pushed function: StartButton_A1
        function startActivity1(app, event)
            app.recorded=[];
            app.A1_gameOn=0;
            app.A1_gamePaused=0;
            app.A1_ThresholdCrossed=0;
            app.A1_pauseCounter=1;
            app.HRNumLabel_A1.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A1.Text='--';

            app.StartButton_A1.Visible="on";
            app.StartButton_A1.Enable="off";
            app.InhaleExhaleLabel_A1.Text="";

            app.StatusLabel_A1.Text="Ready to Start";
            %Game set up
            %Reset the plot
            app.A1_TimerCounter=app.A1total_time/app.A1Timer_period; %how many times the timer callback will be done in 120s
            
            app.A1_gameComplete=0;
            cla(app.UIAxes_A1);
            axis(app.UIAxes_A1,[0 6 0 6]);
            hold(app.UIAxes_A1,'on');

            %Set Count to 0
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.A1_Count=0;
            app.A1_Score=0;
            app.A1_HRCount=0;

            %Set score to 0
            %Set TimerCount to 0
            %Activity 1 timer

            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text=string(app.A1_time_str(1));

            %Ready to play?

            %show the threshold circle 

            app.threshold=1.9;
            app.threshold_exhale=1.2;
            
            h_thrs=viscircles(app.UIAxes_A1,[3 3],app.threshold,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
            
            %show button to start timer

            app.InstructionsField.Text='You have 2 minutes to take 5 diaphragmatic breaths. Ready to begin? Press Play';

            app.PlayButton_A1.Visible="on";
            app.PlayButton_A1.Enable="on";
            app.PauseButton_A1.Visible="on";
            app.PauseButton_A1.Enable="off";
            app.StopButton_A1.Visible="on";
            app.StopButton_A1.Enable="off";

            app.SaveScoreButton_A1.Visible="off";
            app.SaveScoreButton_A1.Enable="off";
            app.DiscardScoreButton_A1.Visible="off";
            app.DiscardScoreButton_A1.Enable="off";
            app.TimestampLabel_A1.Visible="off";
            app.TimestampNumLabel_A1.Visible="off";
            app.TimestampNumLabel_A1.Text="";

            % simulateDAQ_A1(app);

            mydaq=daq("adi"); %create DAQ Object called mydaq
            addinput(mydaq,'smu1','a','Voltage'); %Add chA as the input
            addinput(mydaq,'smu1','b','Voltage'); %Add chB as the input
            mydaq.ScansAvailableFcnCount=app.aq_dataT*mydaq.Rate; %set samples aquired to 0.5s
            app.d=mydaq; %store DAQ object to app property d
            app.dInfo=mydaq.Channels.Device; %store DAQ properties to app property dInfo
          
            disp('DAQ initialized');

            app.DAQstarted=1;
            %Aqcuiring and plotting
            % create callback function to call plotA1 every time 0.1s scans available
            app.d.ScansAvailableFcn = @(src,evt) plotA1(app,src,evt);    
            % start acquiring and plotting
            start(app.d, "continuous");
    
            disp('Activity 1 started');
        end

        % Button pushed function: StartButton_A2
        function startActivity2(app, event)
            app.A2_recorded=[];
            app.A2_BreathCount=[0 0 0];
            app.A2_gameOn=0;
            app.A2_gamePaused=0;
            app.A2_ThresholdCrossed=0;
            app.A2_pauseCounter=1;
            app.Round1NumLabel_A2.Text="--";
            app.Round2NumLabel_A2.Text="--";
            app.Round3NumLabel_A2.Text="--";
            app.countNum_A2.Text="";
            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A2.Text='--';

            app.StartButton_A2.Visible="on";
            app.StartButton_A2.Enable="off";
            app.InhaleExhaleLabel_A2.Text="";

            app.StatusLabel_A2.Text="Ready to Start";
            
            %Game set up
            %Reset Timer

            app.A2_TimerCounter=app.A2total_time/app.A2Timer_period; %how many times the timer callback will be done in 180s
            app.A2_pauseCounter=1;
            app.A2_RoundCount=[];

            %reset plot
            app.A2_gameComplete=0;
            cla(app.UIAxes_A2_ProgressBar);
            cla(app.UIAxes_A2);
            axis(app.UIAxes_A2,[0 6 0 6]);
            hold(app.UIAxes_A2,'on');

            %Set Count to 0
            % app.CountLabel_A2.Text='Count:';
            app.countNum_A2.Text='0';
            app.A2_Count=0;
            app.A2_HRCount=0; %tracks the number of times the callback has been called so you can wait enough time to calculate the HR
            
            %Set score to 0
            app.A2_Score=0;

            %Ready to play?

            %show the threshold circle 

            app.threshold=1.9;
            app.threshold_exhale=1.2;
            
            h_thrs=viscircles(app.UIAxes_A2,[3 3],app.threshold,'Color',[0.01,0.00,0.53],'LineStyle','--','LineWidth',5);
            
            %show button to start timer

            app.InstructionsField_A2.Text='Take as many steady diaphragmatic breaths as you can within 3 rounds of 30s. Ready to begin? Press Play';

            app.PlayButton_A2.Visible="on";
            app.PlayButton_A2.Enable="on";
            app.PauseButton_A2.Visible="on";
            app.PauseButton_A2.Enable="off";
            app.StopButton_A2.Visible="on";
            app.StopButton_A2.Enable="off";

            app.SaveScoreButton_A2.Visible="off";
            app.SaveScoreButton_A2.Enable="off";
            app.DiscardScoreButton_A2.Visible="off";
            app.DiscardScoreButton_A2.Enable="off";
            app.TimestampLabel_A2.Visible="off";
            app.TimestampNumLabel_A2.Visible="off";
            app.TimestampNumLabel_A2.Text="";

            % simulateDAQ_A1(app);

            mydaq=daq("adi"); %create DAQ Object called mydaq
            addinput(mydaq,'smu1','a','Voltage'); %Add chA as the input
            addinput(mydaq,'smu1','b','Voltage'); %Add chB as the input
            mydaq.ScansAvailableFcnCount=app.aq_dataT*mydaq.Rate; %set samples aquired to 0.5s
            app.d=mydaq; %store DAQ object to app property d
            app.dInfo=mydaq.Channels.Device; %store DAQ properties to app property dInfo
          
            disp('DAQ initialized');

            app.DAQstarted=1;
            %Aqcuiring and plotting
            % create callback function to call plotPPG every time 2s scans available
            app.d.ScansAvailableFcn = @(src,evt) plotA2(app,src,evt);    
            % start acquiring and plotting
            start(app.d, "continuous");
    
            disp('Activity 2 started');
        end

        % Button pushed function: PlayButton_A1
        function play_A1(app, event)
            %Start Timer
            app.A1_gameOn=1;
            app.A1_gameComplete=0;
            app.PlayTutorialButton.Visible="on";
            app.PlayTutorialButton.Enable="off";
            app.PauseButton_A1.Visible="on";
            app.PauseButton_A1.Enable="on";
            app.StopButton_A1.Visible="on";
            app.StopButton_A1.Enable="on";
            app.SaveScoreButton_A1.Visible="off";
            app.SaveScoreButton_A1.Enable="off";
            app.DiscardScoreButton_A1.Visible="off";
            app.DiscardScoreButton_A1.Enable="off";
            
            app.StatusLabel_A1.Text="In Progress";
            app.TimestampNumLabel_A1.Visible="off";
            app.TimestampNumLabel_A1.Text="";
            app.InhaleExhaleLabel_A1.Text="";
            app.HRNumLabel_A1.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A1.Text='--';
            
            %temporarily initializing this
            app.A1_PreviousVal=-1;
            app.DAQstarted=1;
            A1_StartTimer(app);
            
        end

        % Button pushed function: StopButton_A1
        function stop_A1(app, event)
            %Stop Timer
            app.A1_gameOn=0;
            app.A1_gamePaused=0;
            % pause(.5);

            app.PlayButton_A1.Visible="on";
            app.PlayButton_A1.Enable="off";
            app.PauseButton_A1.Visible="on";
            app.PauseButton_A1.Enable="off";
            app.StopButton_A1.Visible="on";
            app.StopButton_A1.Enable="off";
            app.PlayTutorialButton.Visible="on";
            app.PlayTutorialButton.Enable="on";
         
            app.StatusLabel_A1.Text="Game Stopped";
            app.TimestampLabel_A1.Visible="on";
            app.TimestampNumLabel_A1.Visible="on";
            app.TimestampNumLabel_A1.Text=string(datetime("now"));
            app.InhaleExhaleLabel_A1.Text="";

            app.A1_simulateDAQCounter=1;
            app.A1_TimerCounter=app.A1total_time/app.A1Timer_period;
            cla(app.UIAxes_A1);
            axis(app.UIAxes_A1,[0 6 0 6]);
            hold(app.UIAxes_A1,'on');

            app.StartButton_A1.Visible="on";
            app.StartButton_A1.Enable="on";

            app.HRNumLabel_A1.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A1.Text='--';
            app.A1_HRCount=0;

            %stop(app.A1_simulateDAQTimer);
            %stop daq
            stop(app.d);
            flush(app.d);                     %clear data in daq buffer
            app.DAQstarted=0;

        end

        % Button pushed function: PauseButton_A1
        function pause_A1(app, event)
            %Pause Timer
            app.A1_pauseCounter=app.A1_TimerCounter;
            stop(app.A1_Timer);

            app.PlayButton_A1.Visible="on";
            app.PlayButton_A1.Enable="on";
            app.PauseButton_A1.Visible="on";
            app.PauseButton_A1.Enable="off";
            
            app.A1_gameOn=1;
            app.A1_gamePaused=1;
            app.DAQstarted=1;
            
            app.StatusLabel_A1.Text="Game Paused";
            app.TimestampNumLabel_A1.Visible="off";
            app.TimestampNumLabel_A1.Text="";
            app.InhaleExhaleLabel_A1.Text="Paused";
            app.A1_HRCount=0;
            app.HRNumLabel_A1.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A1.Text='--';
            
        end

        % Button pushed function: SaveScoreButton_A1
        function saveScore_A1(app, event)
            app.StartButton_A1.Visible="on";
            app.StartButton_A1.Enable="on";
            app.SaveScoreButton_A1.Visible="off";
            app.SaveScoreButton_A1.Enable="off";
            app.DiscardScoreButton_A1.Visible="off";
            app.DiscardScoreButton_A1.Enable="off";
            app.PlayTutorialButton.Visible="on";
            app.PlayTutorialButton.Enable="on";
            app.TimestampLabel_A1.Visible="off";
            app.TimestampNumLabel_A1.Visible="off";

            app.StatusLabel_A1.Text="Score Saved";
            
            %add to user file when we can
            score_tmp=["A1"; app.TimestampNumLabel_A1.Text; app.A1_Score];
            app.user_scores=[app.user_scores score_tmp];
            app.TimestampNumLabel_A1.Text="";
            app.InhaleExhaleLabel_A1.Text="";
            app.countNum.Text="";
            app.HRNumLabel_A1.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A1.Text='--';
            app.ScoreNum_A1.Text=num2str(0);
            app.A1_Score=0;

        end

        % Button pushed function: DiscardScoreButton_A1
        function discardScore_A1(app, event)
            app.StartButton_A1.Visible="on";
            app.StartButton_A1.Enable="on";
            app.SaveScoreButton_A1.Visible="off";
            app.SaveScoreButton_A1.Enable="off";
            app.DiscardScoreButton_A1.Visible="off";
            app.DiscardScoreButton_A1.Enable="off";
            app.TimestampLabel_A1.Visible="off";
            app.TimestampNumLabel_A1.Visible="off";
            app.PlayTutorialButton.Visible="on";
            app.PlayTutorialButton.Enable="on";
            app.countNum.Text="";
            app.HRNumLabel_A1.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A1.Text='--';

            app.StatusLabel_A1.Text="Score Discarded";

            app.TimestampNumLabel_A1.Text="";
            app.InhaleExhaleLabel_A1.Text="";
            app.ScoreNum_A1.Text=num2str(0);
            app.A1_Score=0;


        end

        % Button pushed function: PlayButton_A2
        function play_A2(app, event)
            %Start Timer
            app.A2_gameOn=1;
            app.A2_gameComplete=0;
            app.PlayTutorialButton_A2.Visible="on";
            app.PlayTutorialButton_A2.Enable="off";
            app.PlayButton_A2.Visible="on";
            app.PlayButton_A2.Enable="off";
            app.PauseButton_A2.Visible="on";
            app.PauseButton_A2.Enable="on";
            app.StopButton_A2.Visible="on";
            app.StopButton_A2.Enable="on";
            app.SaveScoreButton_A2.Visible="off";
            app.SaveScoreButton_A2.Enable="off";
            app.DiscardScoreButton_A2.Visible="off";
            app.DiscardScoreButton_A2.Enable="off";
            
            app.StatusLabel_A2.Text="In Progress";
            app.TimestampNumLabel_A2.Visible="off";
            app.TimestampNumLabel_A2.Text="";
            app.InhaleExhaleLabel_A2.Text="";
            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A2.Text='--';

            app.ScoreNum_A2.Text=num2str(0);
            app.A2_Score=0;
            
            %temporarily initializing this
            app.A2_PreviousVal=-1;
            app.DAQstarted=1;
            A2_StartTimer(app);
            %TODO continue timer at the new start time
        end

        % Button pushed function: PauseButton_A2
        function pause_A2(app, event)
            %Pause Timer
            app.A2_pauseCounter=app.A2_TimerCounter;
            stop(app.A2_Timer);

            app.PlayButton_A2.Visible="on";
            app.PlayButton_A2.Enable="on";
            app.PauseButton_A2.Visible="on";
            app.PauseButton_A2.Enable="off";
            
            app.A2_gameOn=1;
            app.A2_gamePaused=1;
            app.DAQstarted=1;
            
            app.StatusLabel_A2.Text="Game Paused";
            app.TimestampNumLabel_A2.Visible="off";
            app.TimestampNumLabel_A2.Text="";
            app.InhaleExhaleLabel_A2.Text="";
            app.A2_HRCount=0; %reset so it starts up when there are 2s built up
            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A2.Text='--';
        end

        % Button pushed function: StopButton_A2
        function stop_A2(app, event)
             %Stop Timer
            app.A2_gameOn=0;
            app.A2_gamePaused=0;

            app.PlayButton_A2.Visible="on";
            app.PlayButton_A2.Enable="off";
            app.PauseButton_A2.Visible="on";
            app.PauseButton_A2.Enable="off";
            app.StopButton_A2.Visible="on";
            app.StopButton_A2.Enable="off";
            app.PlayTutorialButton_A2.Visible="on";
            app.PlayTutorialButton_A2.Enable="on";
         
            app.StatusLabel_A2.Text="Game Stopped";
            app.TimestampLabel_A2.Visible="on";
            app.TimestampNumLabel_A2.Visible="on";
            app.TimestampNumLabel_A2.Text=string(datetime("now"));
            app.InhaleExhaleLabel_A2.Text="";

            
            app.A2_TimerCounter=app.A2total_time/app.A2Timer_period;
            cla(app.UIAxes_A2_ProgressBar);
            cla(app.UIAxes_A2);
            axis(app.UIAxes_A2,[0 6 0 6]);
            hold(app.UIAxes_A2,'on');

            app.StartButton_A2.Visible="on";
            app.StartButton_A2.Enable="on";

            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A2.Text='--';
            app.A2_HRCount=0;
            app.countNum_A2.Text="";

            %stop(app.A1_simulateDAQTimer);
            %stop daq
            stop(app.d);
            flush(app.d);                     %clear data in daq buffer
            app.DAQstarted=0;
        end

        % Button pushed function: SaveScoreButton_A2
        function saveScore_A2(app, event)
            app.StartButton_A2.Visible="on";
            app.StartButton_A2.Enable="on";
            app.SaveScoreButton_A2.Visible="off";
            app.SaveScoreButton_A2.Enable="off";
            app.DiscardScoreButton_A2.Visible="off";
            app.DiscardScoreButton_A2.Enable="off";
            app.PlayTutorialButton_A2.Visible="on";
            app.PlayTutorialButton_A2.Enable="on";
            app.TimestampLabel_A2.Visible="off";
            app.TimestampNumLabel_A2.Visible="off";

            app.StatusLabel_A2.Text="Score Saved";
            
            %add to user file when we can
            score_tmp=["A2"; app.TimestampNumLabel_A2.Text; app.A2_Score];
            app.user_scores=[app.user_scores score_tmp];
            app.TimestampNumLabel_A2.Text="";
            app.InhaleExhaleLabel_A2.Text="";
            app.Round1NumLabel_A2.Text="--";
            app.Round2NumLabel_A2.Text="--";
            app.Round3NumLabel_A2.Text="--";
            app.countNum_A2.Text="";
            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A2.Text='--';
            app.ScoreNum_A2.Text=num2str(0);
            app.A2_Score=0;
            app.A2_BreathCount=[0 0 0];
        end

        % Button pushed function: DiscardScoreButton_A2
        function discardScore_A2(app, event)
            app.StartButton_A2.Visible="on";
            app.StartButton_A2.Enable="on";
            app.SaveScoreButton_A2.Visible="off";
            app.SaveScoreButton_A2.Enable="off";
            app.DiscardScoreButton_A2.Visible="off";
            app.DiscardScoreButton_A2.Enable="off";
            app.TimestampLabel_A2.Visible="off";
            app.TimestampNumLabel_A2.Visible="off";
            app.PlayTutorialButton_A2.Visible="on";
            app.PlayTutorialButton_A2.Enable="on";

            app.StatusLabel_A2.Text="Score Discarded";

            app.TimestampNumLabel_A2.Text="";
            app.InhaleExhaleLabel_A2.Text="";
            app.Round1NumLabel_A2.Text="--";
            app.Round2NumLabel_A2.Text="--";
            app.Round3NumLabel_A2.Text="--";
            app.countNum_A2.Text="";
            app.HRNumLabel_A2.FontColor=[0.0 0.0 0.0];
            app.HRNumLabel_A2.Text='--';

            app.ScoreNum_A2.Text=num2str(0);
            app.A2_Score=0;
            app.A2_BreathCount=[0 0 0];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializeTab = uitab(app.TabGroup);
            app.InitializeTab.Title = 'Initialize';
            app.UIAxes = uiaxes(app.InitializeTab);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Breathing (AU)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.InitializeTab);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'PPG')
            zlabel(app.UIAxes2, 'Z')
            app.InitializeSensorsButton = uibutton(app.InitializeTab, 'push');
            app.InitializeSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @initDAQ, true);
            app.InitializeSensorsButton.Text = 'Initialize Sensors';
            app.StartLiveReadingButton = uibutton(app.InitializeTab, 'push');
            app.StartLiveReadingButton.ButtonPushedFcn = createCallbackFcn(app, @startDAQ, true);
            app.StartLiveReadingButton.Text = 'Start Live Reading';
            app.StopLiveReadingButton = uibutton(app.InitializeTab, 'push');
            app.StopLiveReadingButton.ButtonPushedFcn = createCallbackFcn(app, @stopDAQ, true);
            app.StopLiveReadingButton.Text = 'Stop Live Reading';
            app.RespirationRatebreathperminuteLabel = uilabel(app.InitializeTab);
            app.RespirationRatebreathperminuteLabel.Text = 'Respiration Rate (breath per minute):';
            app.HeartRatebeatsperminuteLabel = uilabel(app.InitializeTab);
            app.HeartRatebeatsperminuteLabel.Text = 'Heart Rate (beats per minute):';
            app.SignInTab = uitab(app.TabGroup);
            app.SignInTab.Title = 'Sign In';
            app.SignInButton = uibutton(app.SignInTab, 'push');
            app.SignInButton.Text = 'Sign In';
            app.UserNameDropDownLabel = uilabel(app.SignInTab);
            app.UserNameDropDownLabel.Text = 'UserName';
            app.UserNameDropDown = uidropdown(app.SignInTab);
            app.UserNameDropDown.Editable = 'on';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Activity1Tab = uitab(app.TabGroup);
            app.Activity1Tab.Title = 'Activity 1';
            app.Activity1Tab.ButtonDownFcn = createCallbackFcn(app, @initActivity1, true);
            app.UIAxes_A1 = uiaxes(app.Activity1Tab);
            app.UIAxes_A1.XTick = [];
            app.UIAxes_A1.YTick = [];
            app.UIAxes_A1.ZTick = [];
            app.Activity1Complete5diaphragmaticbreathsLabel = uilabel(app.Activity1Tab);
            app.Activity1Complete5diaphragmaticbreathsLabel.Text = 'Activity 1: Complete 5 diaphragmatic breaths';
            app.StartButton_A1 = uibutton(app.Activity1Tab, 'push');
            app.StartButton_A1.ButtonPushedFcn = createCallbackFcn(app, @startActivity1, true);
            app.StartButton_A1.Text = 'Start';
            app.PauseButton_A1 = uibutton(app.Activity1Tab, 'push');
            app.PauseButton_A1.ButtonPushedFcn = createCallbackFcn(app, @pause_A1, true);
            app.PauseButton_A1.Enable = 'off';
            app.PauseButton_A1.Text = 'Pause';
            app.SaveScoreButton_A1 = uibutton(app.Activity1Tab, 'push');
            app.SaveScoreButton_A1.ButtonPushedFcn = createCallbackFcn(app, @saveScore_A1, true);
            app.SaveScoreButton_A1.Enable = 'off';
            app.SaveScoreButton_A1.Visible = 'off';
            app.SaveScoreButton_A1.Text = 'Save Score';
            app.TimeLabel = uilabel(app.Activity1Tab);
            app.TimeLabel.Text = '';
            app.TimeNum = uilabel(app.Activity1Tab);
            app.TimeNum.Text = '';
            app.CountLabel = uilabel(app.Activity1Tab);
            app.CountLabel.Text = '';
            app.countNum = uilabel(app.Activity1Tab);
            app.countNum.Text = '';
            app.UsernameLabel = uilabel(app.Activity1Tab);
            app.UsernameLabel.Text = 'Username:';
            app.UsernameFieldLabel = uilabel(app.Activity1Tab);
            app.UsernameFieldLabel.Text = 'UsernameField';
            app.TimestampLabel_A1 = uilabel(app.Activity1Tab);
            app.TimestampLabel_A1.Visible = 'off';
            app.TimestampLabel_A1.Text = 'Timestamp:';
            app.TimestampNumLabel_A1 = uilabel(app.Activity1Tab);
            app.TimestampNumLabel_A1.Visible = 'off';
            app.TimestampNumLabel_A1.Text = 'TimestampField';
            app.StatusLabel = uilabel(app.Activity1Tab);
            app.StatusLabel.Text = 'Status:';
            app.StatusLabel_A1 = uilabel(app.Activity1Tab);
            app.StatusLabel_A1.Text = 'Incomplete';
            app.StopButton_A1 = uibutton(app.Activity1Tab, 'push');
            app.StopButton_A1.ButtonPushedFcn = createCallbackFcn(app, @stop_A1, true);
            app.StopButton_A1.Enable = 'off';
            app.StopButton_A1.Text = 'Stop';
            app.DiscardScoreButton_A1 = uibutton(app.Activity1Tab, 'push');
            app.DiscardScoreButton_A1.ButtonPushedFcn = createCallbackFcn(app, @discardScore_A1, true);
            app.DiscardScoreButton_A1.Enable = 'off';
            app.DiscardScoreButton_A1.Visible = 'off';
            app.DiscardScoreButton_A1.Text = 'Discard Score';
            app.InstructionsField = uilabel(app.Activity1Tab);
            app.InstructionsField.Text = '';
            app.PlayTutorialButton = uibutton(app.Activity1Tab, 'push');
            app.PlayTutorialButton.ButtonPushedFcn = createCallbackFcn(app, @startTutorial, true);
            app.PlayTutorialButton.Text = 'Play Tutorial';
            app.ScoreLabel = uilabel(app.Activity1Tab);
            app.ScoreLabel.Text = 'Score: ';
            app.HRLabel_A1 = uilabel(app.Activity1Tab);
            app.HRLabel_A1.Text = 'HR:';
            app.HRNumLabel_A1 = uilabel(app.Activity1Tab);
            app.HRNumLabel_A1.Text = '--';
            app.PlayButton_A1 = uibutton(app.Activity1Tab, 'push');
            app.PlayButton_A1.ButtonPushedFcn = createCallbackFcn(app, @play_A1, true);
            app.PlayButton_A1.Enable = 'off';
            app.PlayButton_A1.Text = 'Play';
            app.ScoreNum_A1 = uilabel(app.Activity1Tab);
            app.ScoreNum_A1.Text = '0';
            app.InhaleExhaleLabel_A1 = uilabel(app.Activity1Tab);
            app.InhaleExhaleLabel_A1.Text = '';
            app.Activity2Tab = uitab(app.TabGroup);
            app.Activity2Tab.Title = 'Activity 2';
            app.Activity2Tab.ButtonDownFcn = createCallbackFcn(app, @initActivity2, true);
            app.UIAxes_A2 = uiaxes(app.Activity2Tab);
            app.UIAxes_A2.XTick = [];
            app.UIAxes_A2.YTick = [];
            app.UIAxes_A2.ZTick = [];
            app.UIAxes_A2_ProgressBar = uiaxes(app.Activity2Tab);
            xlabel(app.UIAxes_A2_ProgressBar, 'Time(s)')
            app.UIAxes_A2_ProgressBar.XLim = [0 180];
            app.UIAxes_A2_ProgressBar.XTick = [0 30 60 90 120 150 180];
            app.UIAxes_A2_ProgressBar.YTick = [];
            app.UIAxes_A2_ProgressBar.ZTick = [];
            app.Activity2Label = uilabel(app.Activity2Tab);
            app.Activity2Label.Text = 'Activity 2: On/Off Circuits';
            app.StartButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.StartButton_A2.ButtonPushedFcn = createCallbackFcn(app, @startActivity2, true);
            app.StartButton_A2.Text = 'Start';
            app.PauseButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.PauseButton_A2.ButtonPushedFcn = createCallbackFcn(app, @pause_A2, true);
            app.PauseButton_A2.Enable = 'off';
            app.PauseButton_A2.Text = 'Pause';
            app.SaveScoreButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.SaveScoreButton_A2.ButtonPushedFcn = createCallbackFcn(app, @saveScore_A2, true);
            app.SaveScoreButton_A2.Enable = 'off';
            app.SaveScoreButton_A2.Visible = 'off';
            app.SaveScoreButton_A2.Text = 'Save Score';
            app.UsernameLabel_A2 = uilabel(app.Activity2Tab);
            app.UsernameLabel_A2.Text = 'Username:';
            app.UsernameFieldLabel_A2 = uilabel(app.Activity2Tab);
            app.UsernameFieldLabel_A2.Text = 'UsernameField';
            app.TimestampLabel_A2 = uilabel(app.Activity2Tab);
            app.TimestampLabel_A2.Enable = 'off';
            app.TimestampLabel_A2.Visible = 'off';
            app.TimestampLabel_A2.Text = 'Timestamp:';
            app.TimestampNumLabel_A2 = uilabel(app.Activity2Tab);
            app.TimestampNumLabel_A2.Enable = 'off';
            app.TimestampNumLabel_A2.Visible = 'off';
            app.TimestampNumLabel_A2.Text = 'TimestampField';
            app.StatusLabelTitle_A2 = uilabel(app.Activity2Tab);
            app.StatusLabelTitle_A2.Text = 'Status:';
            app.StatusLabel_A2 = uilabel(app.Activity2Tab);
            app.StatusLabel_A2.Text = 'Complete';
            app.StopButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.StopButton_A2.ButtonPushedFcn = createCallbackFcn(app, @stop_A2, true);
            app.StopButton_A2.Visible = 'off';
            app.StopButton_A2.Text = 'Stop';
            app.DiscardScoreButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.DiscardScoreButton_A2.ButtonPushedFcn = createCallbackFcn(app, @discardScore_A2, true);
            app.DiscardScoreButton_A2.Enable = 'off';
            app.DiscardScoreButton_A2.Visible = 'off';
            app.DiscardScoreButton_A2.Text = 'Discard Score';
            app.PlayTutorialButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.PlayTutorialButton_A2.ButtonPushedFcn = createCallbackFcn(app, @startTutorial2, true);
            app.PlayTutorialButton_A2.Text = 'Play Tutorial';
            app.ScoreLabel_A2 = uilabel(app.Activity2Tab);
            app.ScoreLabel_A2.Text = 'Score: ';
            app.HRLabel_A2 = uilabel(app.Activity2Tab);
            app.HRLabel_A2.Text = 'HR:';
            app.InstructionsField_A2 = uilabel(app.Activity2Tab);
            app.InstructionsField_A2.Text = '';
            app.ProgressLabel = uilabel(app.Activity2Tab);
            app.ProgressLabel.Text = '';
            app.CountLabel_A2 = uilabel(app.Activity2Tab);
            app.CountLabel_A2.Text = 'Breath Count:';
            app.Round2Label = uilabel(app.Activity2Tab);
            app.Round2Label.Text = 'Round 2:';
            app.Round3Label = uilabel(app.Activity2Tab);
            app.Round3Label.Text = 'Round 3:';
            app.countNum_A2 = uilabel(app.Activity2Tab);
            app.countNum_A2.Text = '';
            app.Round2NumLabel_A2 = uilabel(app.Activity2Tab);
            app.Round2NumLabel_A2.Text = '--';
            app.Round3NumLabel_A2 = uilabel(app.Activity2Tab);
            app.Round3NumLabel_A2.Text = '--';
            app.HRNumLabel_A2 = uilabel(app.Activity2Tab);
            app.HRNumLabel_A2.Text = '--';
            app.Round1Label = uilabel(app.Activity2Tab);
            app.Round1Label.Text = 'Round 1:';
            app.Round1NumLabel_A2 = uilabel(app.Activity2Tab);
            app.Round1NumLabel_A2.Text = '--';
            app.InhaleExhaleLabel_A2 = uilabel(app.Activity2Tab);
            app.InhaleExhaleLabel_A2.Text = '';
            app.PlayButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.PlayButton_A2.ButtonPushedFcn = createCallbackFcn(app, @play_A2, true);
            app.PlayButton_A2.Enable = 'off';
            app.PlayButton_A2.Text = 'Play';
            app.ScoreNum_A2 = uilabel(app.Activity2Tab);
            app.ScoreNum_A2.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Respiration_25Nov24_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @preLoad)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end