classdef Screen1_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        UserNameEditField       matlab.ui.control.EditField
        UserNameEditFieldLabel  matlab.ui.control.Label
        StartCalibrationButton  matlab.ui.control.Button
        AnnouncementLabel       matlab.ui.control.Label
        StartActivityButton     matlab.ui.control.Button
        InitiateDAQButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Message Components
        MainFigure % Handle to the main GUI figure
        MessageText % Handle to the text area for display
    
        % Inserting Relevent Shapes
        OriginalImage;
        Announcement = insertShape(I,"rectangle", [33,448,187,22], Linewidth = 5)


        function obj = MyGUIApp()
            % Constructor: Create GUI layout
            obj.MainFigure = figure('Name', 'Main GUI App', 'Position', [300, 300, 400, 200]);
            
            % Create text display area for messages
            obj.MessageText = uicontrol('Parent', obj.MainFigure, ...
                                        'Style', 'text', ...
                                        'Position', [50, 80, 300, 40], ...
                                        'FontSize', 12, ...
                                        'HorizontalAlignment', 'center', ...
                                        'String', 'Welcome!');
            
            % Create button with a callback to demonstrate message update
            uicontrol('Parent', obj.MainFigure, ...
                      'Style', 'pushbutton', ...
                      'Position', [150, 30, 100, 30], ...
                      'String', 'Show Message', ...
                      'Callback', @(~,~) obj.updateMessage('Button Clicked!'));
        end
       function updateMessage(obj, newMessage)
            % Method to update the message text in the GUI
            set(obj.MessageText, 'String', newMessage);
        end

  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function InitDAQ(app, event)
            updateMessage(obj, ')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Calibration Data')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Sensor Data')
            zlabel(app.UIAxes, 'Z')
            app.InitiateDAQButton = uibutton(app.UIFigure, 'push');
            app.InitiateDAQButton.Text = 'Initiate DAQ';
            app.StartActivityButton = uibutton(app.UIFigure, 'push');
            app.StartActivityButton.Text = 'Start Activity';
            app.AnnouncementLabel = uilabel(app.UIFigure);
            app.AnnouncementLabel.Text = 'Announcement:';
            app.StartCalibrationButton = uibutton(app.UIFigure, 'push');
            app.StartCalibrationButton.Text = 'Start Calibration ';
            app.UserNameEditFieldLabel = uilabel(app.UIFigure);
            app.UserNameEditFieldLabel.Text = 'UserName';
            app.UserNameEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Screen1_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end